classdef TS_mark_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        vision1420250927Label  matlab.ui.control.Label
        Button_stop            matlab.ui.control.Button
        Label_start_index      matlab.ui.control.Label
        Label_outdir           matlab.ui.control.Label
        Label_indir            matlab.ui.control.Label
        Button                 matlab.ui.control.Button
        EditField_outdir       matlab.ui.control.EditField
        Label_3                matlab.ui.control.Label
        Button_outdir          matlab.ui.control.Button
        EditField_indir        matlab.ui.control.EditField
        Label_2                matlab.ui.control.Label
        Button_indir           matlab.ui.control.Button
        EditField_start_index  matlab.ui.control.NumericEditField
        Label                  matlab.ui.control.Label
        TS_MarkAPPv14Label     matlab.ui.control.Label
    end

    
    properties (Access = public)
        stopFlag=false; % 停止的flag
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_indir
        function Button_indirPushed(app, event)
            % 选择一个文件夹
            folder_in = uigetdir;
            if folder_in ~= 0
                app.EditField_indir.Value = folder_in;
            else
                app.EditField_indir.Value = '未选择文件夹';
            end
        end

        % Button pushed function: Button_outdir
        function Button_outdirPushed(app, event)
            % 选择一个文件夹
            folder_out = uigetdir;
            if folder_out ~= 0
                app.EditField_outdir.Value = folder_out;
            else
                app.EditField_outdir.Value = '未选择文件夹';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            in_dir=app.EditField_indir.Value;
            out_dir=app.EditField_outdir.Value;
            % in dir check
            if isempty(in_dir)
                app.Label_indir.Text = '⚠️ 请选择一个文件夹';
                return;
            elseif ~isfolder(in_dir)
                app.Label_indir.Text = '❌ 文件夹不存在，请重新输入';
                return;
            else
                app.Label_indir.Text = '';
            end

            % out dir check
            if isempty(out_dir)
                app.Label_outdir.Text = '⚠️ 请选择一个文件夹';
                return;
            elseif ~isfolder(out_dir)
                app.Label_outdir.Text = '❌ 文件夹不存在，请重新输入';
                return;
            else
                app.Label_outdir.Text = '';
            end
            
            % start index check
            start_index=app.EditField_start_index.Value;
            if start_index==0
                app.Label_start_index.Text='❌ 序列不可能是0';
            else
                app.Label_start_index.Text='';
            end

            TS_mark_v1_4_app(app,start_index,in_dir,out_dir);
        end

        % Callback function
        function Button_2ValueChanged(app, event)
            value = app.Button_stop.Value;
            if value==0
                if app.stopFlag
                    
                else
                    app.stopFlag=false;
                end
            else
                in_dir=app.EditField_indir.Value;
                out_dir=app.EditField_outdir.Value;
                % in dir check
                if isempty(in_dir)
                    app.Label_indir.Text = '⚠️ 请选择一个文件夹';
                    return;
                elseif ~isfolder(in_dir)
                    app.Label_indir.Text = '❌ 文件夹不存在，请重新输入';
                    return;
                else
                    app.Label_indir.Text = '';
                end
    
                % out dir check
                if isempty(out_dir)
                    app.Label_outdir.Text = '⚠️ 请选择一个文件夹';
                    return;
                elseif ~isfolder(out_dir)
                    app.Label_outdir.Text = '❌ 文件夹不存在，请重新输入';
                    return;
                else
                    app.Label_outdir.Text = '';
                end
                
                % start index check
                start_index=app.EditField_start_index.Value;
                if start_index==0
                    app.Label_start_index.Text='❌ 序列不可能是0';
                else
                    app.Label_start_index.Text='';
                end
                app.stopFlag = false;
                TS_mark_v1_4_app(app,start_index,in_dir,out_dir);
            end
        end

        % Button pushed function: Button_stop
        function Button_stopPushed(app, event)
            app.stopFlag = true;   % 点击停止
            uialert(app.UIFigure,'已点击停止按钮，按回车键结束标记','提示');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TS_MarkAPPv14Label = uilabel(app.UIFigure);
            app.TS_MarkAPPv14Label.Text = 'TS_Mark APP v1.4';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '请选择开始的序号';
            app.EditField_start_index = uieditfield(app.UIFigure, 'numeric');
            app.Button_indir = uibutton(app.UIFigure, 'push');
            app.Button_indir.ButtonPushedFcn = createCallbackFcn(app, @Button_indirPushed, true);
            app.Button_indir.Text = '选择路径';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '请选择输入文件夹';
            app.EditField_indir = uieditfield(app.UIFigure, 'text');
            app.Button_outdir = uibutton(app.UIFigure, 'push');
            app.Button_outdir.ButtonPushedFcn = createCallbackFcn(app, @Button_outdirPushed, true);
            app.Button_outdir.Text = '选择路径';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '请选择输出文件夹';
            app.EditField_outdir = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始选图';
            app.Label_indir = uilabel(app.UIFigure);
            app.Label_indir.Text = '';
            app.Label_outdir = uilabel(app.UIFigure);
            app.Label_outdir.Text = '';
            app.Label_start_index = uilabel(app.UIFigure);
            app.Label_start_index.Text = '';
            app.Button_stop = uibutton(app.UIFigure, 'push');
            app.Button_stop.ButtonPushedFcn = createCallbackFcn(app, @Button_stopPushed, true);
            app.Button_stop.Text = '结束选图';
            app.vision1420250927Label = uilabel(app.UIFigure);
            app.vision1420250927Label.Text = 'vision 1.4 2025/09/27';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TS_mark_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end